classdef sunpy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SunpyAppUIFigure  matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        SunpyLabel        matlab.ui.control.Label
        Slider            matlab.ui.control.Slider
        UITable           matlab.ui.control.Table
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TT % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setup
            [latitude, longitude, df] = pyrunfile("script.py", [ "latitude" "longitude" "df" ]);
            app.TT = table(df);
            app.TT.Properties.VariableNames = ["time","amount","amount_min"]
            app.UITable.Data = app.TT;
            plot(app.UIAxes,app.TT.time,app.TT.amount)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SunpyAppUIFigure = uifigure('Visible', 'off');
            app.SunpyAppUIFigure.Name = 'Sunpy App';
            app.GridLayout = uigridlayout(app.SunpyAppUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Eclipse')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amount')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Slider = uislider(app.GridLayout);
            app.SunpyLabel = uilabel(app.GridLayout);
            app.SunpyLabel.Text = 'Sunpy';
            % Show the figure after all components are created
            app.SunpyAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sunpy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SunpyAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SunpyAppUIFigure)
        end
    end
end